classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VerticalDistanceEditField       matlab.ui.control.NumericEditField
        VerticalDistanceEditFieldLabel  matlab.ui.control.Label
        HorizontalDistanceEditField     matlab.ui.control.NumericEditField
        HorizontalDistanceEditFieldLabel  matlab.ui.control.Label
        WallCheckBox                    matlab.ui.control.CheckBox
        AngleEditField                  matlab.ui.control.NumericEditField
        AngleLabel                      matlab.ui.control.Label
        VelocityEditField               matlab.ui.control.NumericEditField
        VelocityLabel                   matlab.ui.control.Label
        InitialHeightEditField          matlab.ui.control.NumericEditField
        InitialHeightLabel              matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        StartAnimationButton            matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartAnimationButton
        function StartAnimationButtonPushed(app, event)
      
        %Seting inputs
        initial_velocity = app.VelocityEditField.Value; % This code allowed 
        % the user to input their data into the GUI for initla veloicty
        % height and angle
        angle = app.AngleEditField.Value;
        height = app.InitialHeightEditField.Value;
        gravity = 9.81;

        %Calcualte Horizontal and Verticle Compoents/ veloicty
        speed_Hoz = (initial_velocity * cosd(angle));
        speed_Vert = (initial_velocity * sind(angle));

        %The time settings/array
        time_step = 0.01; % This is the amount of revolutions in the 
        % simulation and set it to 0.01 seconds
        Maximum_time = (2 * speed_Vert / gravity + 5); % The maximum time 
        % is clauclted and this is due to the verticle veloicty
        time = 0:time_step:Maximum_time;

        %Projectile equation horizontal and vertical positions
        Hoz_pos = (speed_Hoz * time);
        Vert_pos = (height + speed_Vert * time - 0.5 * gravity * time.^2);

        %Wall
        Enable_Wall = app.WallCheckBox.Value; %If wall box ticked wall exist
        if Enable_Wall
            wall_distance = app.HorizontalDistanceEditField.Value; %Will get a input for distance
            wall_height = app.VerticalDistanceEditField.Value; %and for Height as well
        end
        
        %Stops Simulation when projectile hits ground
        index_hits_G = find(Vert_pos < 0, 1);

        if ~isempty(index_hits_G)
            Pos_stop_index = min(index_hits_G);
        else
            Pos_stop_index = length(time);
        end

        %Reduces the amount of data arrays
        Hoz_pos = Hoz_pos(1:Pos_stop_index);
        Vert_pos = Vert_pos(1:Pos_stop_index);
        time = time(1:Pos_stop_index);

        %Plot the graph/trajectory
        cla(app.UIAxes);
        grid(app.UIAxes, 'on');
        hold(app.UIAxes,'on')

        plot(app.UIAxes, Hoz_pos, Vert_pos, 'b', 'Linewidth', 1.5);
        Point = plot(app.UIAxes, Hoz_pos(1), Vert_pos(1), 'go', 'MarkerFaceColor','g');

        if Enable_Wall
            plot(app.UIAxes, [wall_distance, wall_distance], [0 wall_height], 'r', 'LineWidth', 2);
        end

        %Displaying the Trajcetory and displaying it on a Legend
        legend_Display = sprintf('Projectile\nCoordinate: (%.2f, %.2f)\nTime: %.2fs\nVelocity: %.2fm/s\nAngle: %.2f°', ...
            Hoz_pos(1), Vert_pos(1), time(1), initial_velocity, angle);

        legend_Key = legend(app.UIAxes, {'Trajectory',legend_Display}, 'Location','best');

        %Storing the data for the slider
        app.Slider.UserData.Hoz_pos = Hoz_pos;
        app.Slider.UserData.Vert_pos = Vert_pos;
        app.Slider.UserData.time = time;

        app.Slider.UserData.speed_Hoz = speed_Hoz;
        app.Slider.UserData.speed_Vert = speed_Vert;
        app.Slider.UserData.gravity = gravity;
        app.Slider.UserData.Marker = Point;
        app.Slider.UserData.legend_Key = legend_Key;

        app.Slider.Limits = [1 length(Hoz_pos)];
        app.Slider.Value = 1;
        app.Slider.Enable = 'on';

        % Animation of the projectile
        HitsWall = false; % Allows to track the projectile when hits the wall

        predictedL = length(Hoz_pos); % This determins the amount of points
        % on the trajcetory in the animation
        
        for An = 1:predictedL % Loops through each point on the trajectory 
            % of the projectile
            Hoz = Hoz_pos(An);
            Vert = Vert_pos(An);
            t = time(An);
            
            set(Point, 'XData', Hoz, 'YData', Vert);% This updates the marker to 
            % correct the current positon
            
        if Enable_Wall && Hoz >= wall_distance 
            if Vert <= wall_height && Vert >= 0 %Checks if the projectile hist the wall
            title(app.UIAxes, 'The Projectile hits the wall','Color', 'g'); % The projectile hits the wall colored green
            HitsWall = true;
            break; % Stops the animation as a loop when the projectile hits the wall
            end
        end
        
        animation_vert = (speed_Vert - gravity * t); %Instantaneouse Velocity
        animation_Hoz  = speed_Hoz;
        velocity_mag   = hypot(animation_Hoz, animation_vert); %Calcualtes the overal speed
        Velocity_angle = atan2d(animation_vert, animation_Hoz); % This calcualtes the animation direction in degrees
        
        %This updates the legend and displays it the projectile
        %infromation
        legend_Display = sprintf('Projectile\nCoordinate: (%.2f, %.2f)\nTime: %.2fs\nVelocity: %.2fm/s\nAngle: %.2f°'...
            ,Hoz, Vert, t, velocity_mag, Velocity_angle);
        set(legend_Key, 'String', {'Trajectory', legend_Display});% This updates the legend with the latest information
        
        pause(time_step); % Pauses the time step above and stops projectile on animation
        end
        
        if Enable_Wall && ~HitsWall % Checks the animation if the projectile hits the wall or not
            if max(Hoz_pos) < wall_distance % The projectilee never reaches the horiontal wall
                title(app.UIAxes, 'The Projectile did not reach the wall','Color', 'r');
            elseif max(Vert_pos(Hoz_pos >= wall_distance)) > wall_height % If the projectile goes above wall wall
                title(app.UIAxes, 'The Projectile goes above the wall', 'Color', 'p');
            end
        end
        hold(app.UIAxes, 'off'); %Resets the axes after the animation
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
        cla(app.UIAxes); %It clears the plotted content for the UI axes
        app.Slider.Value = 1;
        app.Slider.Enable = 'off'; % This resets the slider for the 
        % inital value 
        if isfield(app.Slider.UserData,'Marker') %This deletes the green 
            % dot if it exist for the UserData
            delete(app.Slider.UserData.Marker)%This deletes the text which is linked with the slider
        end
        if isfield(app.Slider.UserData,'txt') %This deletes the text animation
            delete(app.Slider.UserData.txt)
        end
        app.Slider.UserData = [];% This clears the UserData field and removes the data
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
        %slider
        if isfield(app.Slider.UserData,'Marker') && isfield(app.Slider.UserData,'legend_Key')
             index = round(app.Slider.Value); %This converts the slider into a array index

        %Stores Projectile data     
        Hoz_pos = app.Slider.UserData.Hoz_pos;
        Vert_pos = app.Slider.UserData.Vert_pos;
        time = app.Slider.UserData.time; 
        speed_Hoz = app.Slider.UserData.speed_Hoz;
        speed_Vert = app.Slider.UserData.speed_Vert;
        gravity = app.Slider.UserData.gravity;

        Marker = app.Slider.UserData.Marker;
        legend_Key = app.Slider.UserData.legend_Key;

        %This updates the psotion of the green dot on the graph
        set(Marker, 'XData', Hoz_pos(index), 'YData', Vert_pos(index));

        %This computes the velocity componet and the current time
        t = time(index); % This the current time
        Vx = speed_Hoz; % horizontal Velocity
        Vy = speed_Vert - gravity * t;
        vel_mag = sqrt(Vx^2 + Vy^2); % Magnitude for the velocity
        vel_angle = atan2d(Vy, Vx);

       legend_Display = sprintf('Projectile,\n Coordinate: (%.2f, %.2f)\n Time: %.2fs\n Velocity: %.2fm/s\n Angle: %.2f°', ...
            Hoz_pos(index), Vert_pos(index), t, vel_mag, vel_angle);
        
        set(legend_Key, 'String', {'Trajectory',legend_Display});
        end
        end

        % Value changed function: WallCheckBox
        function WallCheckBoxValueChanged(app, event)
            value = app.WallCheckBox.Value; % This checks the current value for the wall checkbox
            
            if value == 1 %If the checkbox is ticked then it shows the the 
                % wall input
                app.HorizontalDistanceEditField.Visible = 'on';
                app.HorizontalDistanceEditFieldLabel.Visible = 'on';
                app.VerticalDistanceEditField.Visible = 'on';
                app.VerticalDistanceEditFieldLabel.Visible = 'on';
            else %If it is not ticked then it remains invissible
                app.HorizontalDistanceEditField.Visible = 'off';
                app.HorizontalDistanceEditFieldLabel.Visible = 'off';
                app.VerticalDistanceEditField.Visible = 'off';
                app.VerticalDistanceEditFieldLabel.Visible = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Projectile Graph')
            xlabel(app.UIAxes, 'Horizontal Distance (m)')
            ylabel(app.UIAxes, 'Verticle Distance (m)')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.StartAnimationButton = uibutton(app.UIFigure, 'push');
            app.StartAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnimationButtonPushed, true);
            app.StartAnimationButton.Text = 'Start Animation';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset Button';
            app.InitialHeightLabel = uilabel(app.UIFigure);
            app.InitialHeightLabel.Text = 'Initial Height:';
            app.InitialHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityLabel = uilabel(app.UIFigure);
            app.VelocityLabel.Text = 'Velocity:';
            app.VelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleLabel = uilabel(app.UIFigure);
            app.AngleLabel.Text = 'Angle:';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.WallCheckBox = uicheckbox(app.UIFigure);
            app.WallCheckBox.ValueChangedFcn = createCallbackFcn(app, @WallCheckBoxValueChanged, true);
            app.WallCheckBox.Text = 'Wall';
            app.HorizontalDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.HorizontalDistanceEditFieldLabel.Visible = 'off';
            app.HorizontalDistanceEditFieldLabel.Text = 'Horizontal Distance';
            app.HorizontalDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.HorizontalDistanceEditField.Visible = 'off';
            app.VerticalDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.VerticalDistanceEditFieldLabel.Visible = 'off';
            app.VerticalDistanceEditFieldLabel.Text = 'Vertical Distance';
            app.VerticalDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticalDistanceEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end